classdef Breakfast < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        EnableBreakfastTimeSwitch       matlab.ui.control.Switch
        EnableBreakfastTimeSwitchLabel  matlab.ui.control.Label
        StartNowLabel                   matlab.ui.control.Label
        Entertimein24hformatLabel       matlab.ui.control.Label
        SetBreakfastTimeLabel           matlab.ui.control.Label
        MinSpinner                      matlab.ui.control.Spinner
        MinSpinnerLabel                 matlab.ui.control.Label
        HourSpinner                     matlab.ui.control.Spinner
        HourSpinnerLabel                matlab.ui.control.Label
        DrinkListBox                    matlab.ui.control.ListBox
        DrinkListBoxLabel               matlab.ui.control.Label
        FoodListBox                     matlab.ui.control.ListBox
        FoodListBoxLabel                matlab.ui.control.Label
        SleepinRobotLabel               matlab.ui.control.Label
        Panel_2                         matlab.ui.container.Panel
        Panel_3                         matlab.ui.container.Panel
        Panel_4                         matlab.ui.container.Panel
        GoButton                        matlab.ui.control.Button
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    
    properties (Access = private)
        MainApp % Main app
    end

    properties (Access = public)
        BreakfastGo
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.MainApp = mainapp;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.MainApp.State = 0;
            app.MainApp.StartBreakfastButton.Enable = "on";
            app.MainApp.StartJoggerButton.Enable = "on";
            delete(app)
        end

        % Button pushed function: GoButton
        function GoButtonPushed(app, event)
            app.BreakfastGo = true;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Panel_4 = uipanel(app.UIFigure);
            app.GoButton = uibutton(app.Panel_4, 'push');
            app.GoButton.ButtonPushedFcn = createCallbackFcn(app, @GoButtonPushed, true);
            app.GoButton.Text = 'Go!';
            app.Panel_3 = uipanel(app.UIFigure);
            app.Panel_2 = uipanel(app.UIFigure);
            app.SleepinRobotLabel = uilabel(app.UIFigure);
            app.SleepinRobotLabel.Text = 'Sleep-in Robot';
            app.FoodListBoxLabel = uilabel(app.UIFigure);
            app.FoodListBoxLabel.Text = 'Food';
            app.FoodListBox = uilistbox(app.UIFigure);
            app.FoodListBox.Items = {'Cereal', 'Toast'};
            app.FoodListBox.Value = 'Cereal';
            app.DrinkListBoxLabel = uilabel(app.UIFigure);
            app.DrinkListBoxLabel.Text = 'Drink';
            app.DrinkListBox = uilistbox(app.UIFigure);
            app.DrinkListBox.Items = {'Apple Juice', 'Orange Juice', 'Milk'};
            app.DrinkListBox.Value = 'Apple Juice';
            app.HourSpinnerLabel = uilabel(app.UIFigure);
            app.HourSpinnerLabel.Text = 'Hour';
            app.HourSpinner = uispinner(app.UIFigure);
            app.HourSpinner.Limits = [0 23];
            app.MinSpinnerLabel = uilabel(app.UIFigure);
            app.MinSpinnerLabel.Text = 'Min';
            app.MinSpinner = uispinner(app.UIFigure);
            app.MinSpinner.Limits = [0 59];
            app.SetBreakfastTimeLabel = uilabel(app.UIFigure);
            app.SetBreakfastTimeLabel.Text = 'Set Breakfast Time:';
            app.Entertimein24hformatLabel = uilabel(app.UIFigure);
            app.Entertimein24hformatLabel.Text = 'Enter time in 24h format';
            app.StartNowLabel = uilabel(app.UIFigure);
            app.StartNowLabel.Text = 'Start Now:';
            app.EnableBreakfastTimeSwitchLabel = uilabel(app.UIFigure);
            app.EnableBreakfastTimeSwitchLabel.Text = 'Enable Breakfast Time';
            app.EnableBreakfastTimeSwitch = uiswitch(app.UIFigure, 'slider');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Breakfast(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end