classdef Jogger < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        UR3ePanel           matlab.ui.container.Panel
        ZButton_8           matlab.ui.control.Button
        ZButton_7           matlab.ui.control.Button
        XButton_8           matlab.ui.control.Button
        YButton_8           matlab.ui.control.Button
        XButton_7           matlab.ui.control.Button
        YButton_7           matlab.ui.control.Button
        ZButton_6           matlab.ui.control.Button
        ZButton_5           matlab.ui.control.Button
        XButton_6           matlab.ui.control.Button
        YButton_6           matlab.ui.control.Button
        XButton_5           matlab.ui.control.Button
        YButton_5           matlab.ui.control.Button
        SendButton_2        matlab.ui.control.Button
        EditField_10        matlab.ui.control.NumericEditField
        EditField_9         matlab.ui.control.NumericEditField
        qEditField_4        matlab.ui.control.NumericEditField
        EditField_8         matlab.ui.control.NumericEditField
        EditField_7         matlab.ui.control.NumericEditField
        qEditField_3        matlab.ui.control.NumericEditField
        ManualControlLabel  matlab.ui.control.Label
        SleepinRobotLabel   matlab.ui.control.Label
        UR10ePanel          matlab.ui.container.Panel
        ZButton_4           matlab.ui.control.Button
        ZButton_3           matlab.ui.control.Button
        XButton_3           matlab.ui.control.Button
        YButton_4           matlab.ui.control.Button
        XButton_4           matlab.ui.control.Button
        YButton_3           matlab.ui.control.Button
        ZButton_2           matlab.ui.control.Button
        ZButton             matlab.ui.control.Button
        XButton_2           matlab.ui.control.Button
        YButton_2           matlab.ui.control.Button
        XButton             matlab.ui.control.Button
        YButton             matlab.ui.control.Button
        SendButton          matlab.ui.control.Button
        EditField_6         matlab.ui.control.NumericEditField
        EditField_5         matlab.ui.control.NumericEditField
        EditField_4         matlab.ui.control.NumericEditField
        qEditField_2        matlab.ui.control.NumericEditField
        EditField_3         matlab.ui.control.NumericEditField
        EditField_2         matlab.ui.control.NumericEditField
        qEditField          matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        MainApp % Main app
        ur10
        ur3
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, ur10, ur3)
            app.MainApp = mainapp;
            app.ur10 = ur10;
            app.ur3 = ur3;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.MainApp.State = 0;
            app.MainApp.StartBreakfastButton.Enable = "on";
            app.MainApp.StartJoggerButton.Enable = "on";
            delete(app)
        end

        % Button pushed function: YButton
        function YButtonPushed(app, event)
            app.ur10.Jog([0 1 0 0 0 0])
        end

        % Button pushed function: YButton_2
        function YButton_2Pushed(app, event)
            app.ur10.Jog([0 -1 0 0 0 0]);
        end

        % Button pushed function: XButton_2
        function XButton_2Pushed(app, event)
            app.ur10.Jog([-1 0 0 0 0 0]);
        end

        % Button pushed function: XButton
        function XButtonPushed(app, event)
            app.ur10.Jog([1 0 0 0 0 0]);
        end

        % Button pushed function: ZButton
        function ZButtonPushed(app, event)
            app.ur10.Jog([0 0 1 0 0 0]);
        end

        % Button pushed function: ZButton_2
        function ZButton_2Pushed(app, event)
            app.ur10.Jog([0 0 -1 0 0 0]);
        end

        % Button pushed function: YButton_3
        function YButton_3Pushed(app, event)
            app.ur10.Jog([0 0 0 0 1 0]);
        end

        % Button pushed function: YButton_4
        function YButton_4Pushed(app, event)
            app.ur10.Jog([0 0 0 0 -1 0]);
        end

        % Button pushed function: XButton_4
        function XButton_4Pushed(app, event)
            app.ur10.Jog([0 0 0 1 0 0]);
        end

        % Button pushed function: XButton_3
        function XButton_3Pushed(app, event)
            app.ur10.Jog([0 0 0 -1 0 0]);
        end

        % Button pushed function: ZButton_3
        function ZButton_3Pushed(app, event)
            app.ur10.Jog([0 0 0 0 0 1]);
        end

        % Button pushed function: ZButton_4
        function ZButton_4Pushed(app, event)
            app.ur10.Jog([0 0 0 0 0 -1]);
        end

        % Button pushed function: SendButton
        function SendButtonPushed(app, event)
            q = [app.qEditField.Value app.EditField_2.Value app.EditField_3.Value app.qEditField_2.Value app.EditField_4.Value app.EditField_5.Value app.EditField_6.Value];
            app.ur10.Teach(q);
        end

        % Button pushed function: SendButton_2
        function SendButton_2Pushed(app, event)
            q = [app.qEditField_3.Value app.EditField_7.Value app.EditField_8.Value app.qEditField_4.Value app.EditField_9.Value app.EditField_10.Value];
            app.ur3.Teach(q);
        end

        % Button pushed function: YButton_5
        function YButton_5Pushed(app, event)
            app.ur3.Jog([0 1 0 0 0 0]);
        end

        % Button pushed function: YButton_6
        function YButton_6Pushed(app, event)
            app.ur3.Jog([0 -1 0 0 0 0]);
        end

        % Button pushed function: XButton_5
        function XButton_5Pushed(app, event)
            app.ur3.Jog([1 0 0 0 0 0]);
        end

        % Button pushed function: XButton_6
        function XButton_6Pushed(app, event)
            app.ur3.Jog([-1 0 0 0 0 0]);
        end

        % Button pushed function: ZButton_5
        function ZButton_5Pushed(app, event)
            app.ur3.Jog([0 0 1 0 0 0]);
        end

        % Button pushed function: ZButton_6
        function ZButton_6Pushed(app, event)
            app.ur3.Jog([0 0 -1 0 0 0]);
        end

        % Button pushed function: YButton_7
        function YButton_7Pushed(app, event)
            app.ur3.Jog([0 0 0 0 1 0]);
        end

        % Button pushed function: YButton_8
        function YButton_8Pushed(app, event)
            app.ur3.Jog([0 0 0 0 -1 0]);
        end

        % Button pushed function: XButton_7
        function XButton_7Pushed(app, event)
            app.ur3.Jog([0 0 0 1 0 0]);
        end

        % Button pushed function: XButton_8
        function XButton_8Pushed(app, event)
            app.ur3.Jog([0 0 0 -1 0 0]);
        end

        % Button pushed function: ZButton_7
        function ZButton_7Pushed(app, event)
            app.ur3.Jog([0 0 0 0 0 1]);
        end

        % Button pushed function: ZButton_8
        function ZButton_8Pushed(app, event)
            app.ur3.Jog([0 0 0 0 0 -1]);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UR10ePanel = uipanel(app.UIFigure);
            app.UR10ePanel.Title = 'UR10e';
            app.qEditField = uieditfield(app.UR10ePanel, 'numeric');
            app.EditField_2 = uieditfield(app.UR10ePanel, 'numeric');
            app.EditField_3 = uieditfield(app.UR10ePanel, 'numeric');
            app.qEditField_2 = uieditfield(app.UR10ePanel, 'numeric');
            app.EditField_4 = uieditfield(app.UR10ePanel, 'numeric');
            app.EditField_5 = uieditfield(app.UR10ePanel, 'numeric');
            app.EditField_6 = uieditfield(app.UR10ePanel, 'numeric');
            app.SendButton = uibutton(app.UR10ePanel, 'push');
            app.SendButton.ButtonPushedFcn = createCallbackFcn(app, @SendButtonPushed, true);
            app.SendButton.Text = 'Send';
            app.YButton = uibutton(app.UR10ePanel, 'push');
            app.YButton.ButtonPushedFcn = createCallbackFcn(app, @YButtonPushed, true);
            app.YButton.Text = '+Y';
            app.XButton = uibutton(app.UR10ePanel, 'push');
            app.XButton.ButtonPushedFcn = createCallbackFcn(app, @XButtonPushed, true);
            app.XButton.Text = '+X';
            app.YButton_2 = uibutton(app.UR10ePanel, 'push');
            app.YButton_2.ButtonPushedFcn = createCallbackFcn(app, @YButton_2Pushed, true);
            app.YButton_2.Text = '-Y';
            app.XButton_2 = uibutton(app.UR10ePanel, 'push');
            app.XButton_2.ButtonPushedFcn = createCallbackFcn(app, @XButton_2Pushed, true);
            app.XButton_2.Text = '-X';
            app.ZButton = uibutton(app.UR10ePanel, 'push');
            app.ZButton.ButtonPushedFcn = createCallbackFcn(app, @ZButtonPushed, true);
            app.ZButton.Text = '+Z';
            app.ZButton_2 = uibutton(app.UR10ePanel, 'push');
            app.ZButton_2.ButtonPushedFcn = createCallbackFcn(app, @ZButton_2Pushed, true);
            app.ZButton_2.Text = '-Z';
            app.YButton_3 = uibutton(app.UR10ePanel, 'push');
            app.YButton_3.ButtonPushedFcn = createCallbackFcn(app, @YButton_3Pushed, true);
            app.YButton_3.Text = '+⟳Y';
            app.XButton_4 = uibutton(app.UR10ePanel, 'push');
            app.XButton_4.ButtonPushedFcn = createCallbackFcn(app, @XButton_4Pushed, true);
            app.XButton_4.Text = '+⟳X';
            app.YButton_4 = uibutton(app.UR10ePanel, 'push');
            app.YButton_4.ButtonPushedFcn = createCallbackFcn(app, @YButton_4Pushed, true);
            app.YButton_4.Text = '-⟲Y';
            app.XButton_3 = uibutton(app.UR10ePanel, 'push');
            app.XButton_3.ButtonPushedFcn = createCallbackFcn(app, @XButton_3Pushed, true);
            app.XButton_3.Text = '-⟲X';
            app.ZButton_3 = uibutton(app.UR10ePanel, 'push');
            app.ZButton_3.ButtonPushedFcn = createCallbackFcn(app, @ZButton_3Pushed, true);
            app.ZButton_3.Text = '+⟳Z';
            app.ZButton_4 = uibutton(app.UR10ePanel, 'push');
            app.ZButton_4.ButtonPushedFcn = createCallbackFcn(app, @ZButton_4Pushed, true);
            app.ZButton_4.Text = '-⟲Z';
            app.SleepinRobotLabel = uilabel(app.UIFigure);
            app.SleepinRobotLabel.Text = 'Sleep-in Robot';
            app.ManualControlLabel = uilabel(app.UIFigure);
            app.ManualControlLabel.Text = 'Manual Control';
            app.UR3ePanel = uipanel(app.UIFigure);
            app.UR3ePanel.Title = 'UR3e';
            app.qEditField_3 = uieditfield(app.UR3ePanel, 'numeric');
            app.EditField_7 = uieditfield(app.UR3ePanel, 'numeric');
            app.EditField_8 = uieditfield(app.UR3ePanel, 'numeric');
            app.qEditField_4 = uieditfield(app.UR3ePanel, 'numeric');
            app.EditField_9 = uieditfield(app.UR3ePanel, 'numeric');
            app.EditField_10 = uieditfield(app.UR3ePanel, 'numeric');
            app.SendButton_2 = uibutton(app.UR3ePanel, 'push');
            app.SendButton_2.ButtonPushedFcn = createCallbackFcn(app, @SendButton_2Pushed, true);
            app.SendButton_2.Text = 'Send';
            app.YButton_5 = uibutton(app.UR3ePanel, 'push');
            app.YButton_5.ButtonPushedFcn = createCallbackFcn(app, @YButton_5Pushed, true);
            app.YButton_5.Text = '+Y';
            app.XButton_5 = uibutton(app.UR3ePanel, 'push');
            app.XButton_5.ButtonPushedFcn = createCallbackFcn(app, @XButton_5Pushed, true);
            app.XButton_5.Text = '+X';
            app.YButton_6 = uibutton(app.UR3ePanel, 'push');
            app.YButton_6.ButtonPushedFcn = createCallbackFcn(app, @YButton_6Pushed, true);
            app.YButton_6.Text = '-Y';
            app.XButton_6 = uibutton(app.UR3ePanel, 'push');
            app.XButton_6.ButtonPushedFcn = createCallbackFcn(app, @XButton_6Pushed, true);
            app.XButton_6.Text = '-X';
            app.ZButton_5 = uibutton(app.UR3ePanel, 'push');
            app.ZButton_5.ButtonPushedFcn = createCallbackFcn(app, @ZButton_5Pushed, true);
            app.ZButton_5.Text = '+Z';
            app.ZButton_6 = uibutton(app.UR3ePanel, 'push');
            app.ZButton_6.ButtonPushedFcn = createCallbackFcn(app, @ZButton_6Pushed, true);
            app.ZButton_6.Text = '-Z';
            app.YButton_7 = uibutton(app.UR3ePanel, 'push');
            app.YButton_7.ButtonPushedFcn = createCallbackFcn(app, @YButton_7Pushed, true);
            app.YButton_7.Text = '+⟳Y';
            app.XButton_7 = uibutton(app.UR3ePanel, 'push');
            app.XButton_7.ButtonPushedFcn = createCallbackFcn(app, @XButton_7Pushed, true);
            app.XButton_7.Text = '+⟳X';
            app.YButton_8 = uibutton(app.UR3ePanel, 'push');
            app.YButton_8.ButtonPushedFcn = createCallbackFcn(app, @YButton_8Pushed, true);
            app.YButton_8.Text = '-⟲Y';
            app.XButton_8 = uibutton(app.UR3ePanel, 'push');
            app.XButton_8.ButtonPushedFcn = createCallbackFcn(app, @XButton_8Pushed, true);
            app.XButton_8.Text = '-⟲X';
            app.ZButton_7 = uibutton(app.UR3ePanel, 'push');
            app.ZButton_7.ButtonPushedFcn = createCallbackFcn(app, @ZButton_7Pushed, true);
            app.ZButton_7.Text = '+⟳Z';
            app.ZButton_8 = uibutton(app.UR3ePanel, 'push');
            app.ZButton_8.ButtonPushedFcn = createCallbackFcn(app, @ZButton_8Pushed, true);
            app.ZButton_8.Text = '-⟲Z';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Jogger(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end