classdef SleepinRobot < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        ESTOPButton           matlab.ui.control.StateButton
        Panel_2               matlab.ui.container.Panel
        StartBreakfastButton  matlab.ui.control.Button
        BreakfastModeLabel_2  matlab.ui.control.Label
        Panel                 matlab.ui.container.Panel
        StartJoggerButton     matlab.ui.control.Button
        JoggingModeLabel      matlab.ui.control.Label
        StatusLamp            matlab.ui.control.Lamp
        StatusLampLabel       matlab.ui.control.Label
        SleepinRobotLabel     matlab.ui.control.Label
    end

    
    properties (Access = public)
        State
        BreakfastApp
        JoggerApp
    end
    
    properties (Access = private)
        ur10
        ur3
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, ur10, ur3)
            app.State = 0;
            app.ur10 = ur10;
            app.ur3 = ur3;
        end

        % Button pushed function: StartBreakfastButton
        function StartBreakfastButtonPushed(app, event)
            app.StartBreakfastButton.Enable = "off";
            app.StartJoggerButton.Enable = "off";
            app.State = 2;
            app.BreakfastApp = Breakfast(app);
        end

        % Button pushed function: StartJoggerButton
        function StartJoggerButtonPushed(app, event)
            app.StartBreakfastButton.Enable = "off";
            app.StartJoggerButton.Enable = "off";
            app.State = 1;
            app.JoggerApp = Jogger(app, app.ur10, app.ur3);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app.BreakfastApp)
            delete(app.JoggerApp)
            delete(app)
        end

        % Value changed function: ESTOPButton
        function ESTOPButtonValueChanged(app, event)
            value = app.ESTOPButton.Value;
            if value
                app.StartBreakfastButton.Enable = "off";
                app.StartJoggerButton.Enable = "off";
                delete(app.BreakfastApp)
                delete(app.JoggerApp)
                app.State = 0;
            else
                app.StartBreakfastButton.Enable = "on";
                app.StartJoggerButton.Enable = "on";
                app.State = 0;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.SleepinRobotLabel = uilabel(app.UIFigure);
            app.SleepinRobotLabel.Text = 'Sleep-in Robot';
            app.StatusLampLabel = uilabel(app.UIFigure);
            app.StatusLampLabel.Text = 'Status';
            app.StatusLamp = uilamp(app.UIFigure);
            app.Panel = uipanel(app.UIFigure);
            app.JoggingModeLabel = uilabel(app.Panel);
            app.JoggingModeLabel.Text = 'Jogging Mode';
            app.StartJoggerButton = uibutton(app.Panel, 'push');
            app.StartJoggerButton.ButtonPushedFcn = createCallbackFcn(app, @StartJoggerButtonPushed, true);
            app.StartJoggerButton.Text = 'Start Jogger';
            app.Panel_2 = uipanel(app.UIFigure);
            app.BreakfastModeLabel_2 = uilabel(app.Panel_2);
            app.BreakfastModeLabel_2.Text = 'Breakfast Mode';
            app.StartBreakfastButton = uibutton(app.Panel_2, 'push');
            app.StartBreakfastButton.ButtonPushedFcn = createCallbackFcn(app, @StartBreakfastButtonPushed, true);
            app.StartBreakfastButton.Text = 'Start Breakfast';
            app.ESTOPButton = uibutton(app.UIFigure, 'state');
            app.ESTOPButton.ValueChangedFcn = createCallbackFcn(app, @ESTOPButtonValueChanged, true);
            app.ESTOPButton.Icon = fullfile(pathToMLAPP, 'assets', 'ESTOP.png');
            app.ESTOPButton.Text = 'E-STOP';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SleepinRobot(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end